classdef PonlineDemo_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ConfigurePanel       matlab.ui.container.Panel
        DeviceDropDown       matlab.ui.control.DropDown
        DeviceDropDownLabel  matlab.ui.control.Label
        ConfigureButton      matlab.ui.control.Button
        RecordPanel          matlab.ui.container.Panel
        OfflineButton        matlab.ui.control.Button
        TemplateButton       matlab.ui.control.Button
        UIAxes_grasp         matlab.ui.control.UIAxes
        UIAxes_angle         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        deviceName 
        probe
        startCommand
        stopCommand
        tcpSocket
        samplingRate
        adn
        gain

        readChannel
        recordChannel
        viewChannel
        readChannelNum
        recordChannelNum
        viewChannelNum
        
        plotEMG
        plotTime
        
        bandpassFilterPara
        combFilterPara

        data_preview
        data_sEMG
        recordFlag
        recordWin

        forceDevice
        forcePara
        mvcData
        mvcPara
        data_force
        plotForce
        plotRefForce
        plotAngle
        plotGrasp
        
        data_labels
        lib_labels

        estimationParameters
        ele_index_all
        stepLen
        munum

        tcp_robot
        refangle
        refforce

        proInd
        
    end
    
    methods (Access = private)
        
        function startRecord(app)
            switch app.deviceName
                case 'MuoviPro'
                    write(app.tcpSocket,app.startCommand,'uint8');
                    while app.tcpSocket.NumBytesAvailable
                        tmp = read(app.tcpSocket,app.tcpSocket.NumBytesAvailable);
                    end
                    app.tcpSocket.NumBytesAvailable
                case 'Quattrocento'
                    % 发送开始命令，然后清空缓冲区
%                     try
%                         fopen(app.tcpSocket);
%                     catch
%                     end
%                     fwrite(app.tcpSocket,app.startCommand);
                    write(app.tcpSocket, uint8(app.startCommand));  % 确保是 uint8 类型
                    pause(0.01)
%                     name = fread(app.tcpSocket,8);
                    name = read(app.tcpSocket,8);
                    disp((char(name')));
                    while app.tcpSocket.BytesAvailable
                        tmp = read(app.tcpSocket,app.tcpSocket.BytesAvailable);
                    end
                    app.tcpSocket.BytesAvailable
            end
        end
        function stopRecord(app)
            switch app.deviceName
                case 'MuoviPro'
                    write(app.tcpSocket,app.stopCommand,'uint8');
                case 'Quattrocento'%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     fwrite(app.tcpSocket,app.stopCommand);
                    write(app.tcpSocket, uint8(app.stopCommand));  % 确保是 uint8 类型

%                     fclose(app.tcpSocket);
                    clear app.tcpSocket
            end 
        end
        function data = readData(app)
            switch app.deviceName
                case 'MuoviPro'
                    tmp = read(app.tcpSocket,app.readChannelNum*app.recordWin,'int16');
                    tmp = reshape(double(tmp),[app.readChannelNum,app.recordWin]);
                    tmpInd = find(tmp>32768);
                    tmp(tmpInd) = tmp(tmpInd)-65536;
                    data = tmp(app.recordChannel,:)*0.000286;
                case 'Quattrocento'
%                     data = fread(app.tcpSocket,[app.readChannelNum,app.recordWin],'int16');

                    rawData = read(app.tcpSocket, app.readChannelNum * app.recordWin, "int16");  % 读取线性数组
                    rawData = double(rawData);
                    data = reshape(rawData, app.readChannelNum, app.recordWin);  % 重塑为矩阵
                    data = data*5/2^app.adn/app.gain*1000;
                    data = data(app.recordChannel,:);%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            end
            
        end
        function data = readAuxData(app,t)
            switch app.forceDevice
                case  'Biometrics'
                    getData(app.forcePara.ch, app.forcePara.fsamp, 1000, app.forcePara.values);
                    tmpData = double(app.forcePara.values.pvData);
                    % tmpData = -tmpData;
                    if app.MVCButton.BackgroundColor==[1,1,1]
                        data = (tmpData-app.mvcPara.mi)/(app.mvcPara.ma-app.mvcPara.mi);  
                    else                        
                        data = tmpData;
                    end
%                     tmpData(isnan(tmpData)) = [];
%                     data = (mean(tmpData)-app.mvcPara.mi)/(app.mvcPara.ma-app.mvcPara.mi);        
                case 'Handwriting'
                    tri = app.TrialSpinner.Value;
                    data = app.lib_labels((tri-1)*10+1:tri*10);
                    ind = ceil((max(t)-5)/2);
                    if ind>0 && ind<=length(data)
                        data = data(ind);
                    else
                        data = NaN;
                        %     set(app.plotForce,'string',data(ind));
                        % else
                        %     set(app.plotForce,'string',[]);
                    end
                    data%%%%%%been deleted
                case 'Gestures'
                    data = max(t);
            end

        end
        function data = filterData(app,data)
            for ch = 1:size(data,1)
                if app.BandpassCheckBox.Value
                    data(ch,:) = filter(app.bandpassFilterPara(1,:),app.bandpassFilterPara(2,:),data(ch,:));
                end
                if app.CombCheckBox.Value
                    data(ch,:) = filter(app.combFilterPara(1,:),app.combFilterPara(2,:),data(ch,:));
                end
            end
        end
        function plotData(app,t,dataToPlot)
            for ch = 1:size(dataToPlot,1)
                set(app.plotEMG{ch},'xdata',t,'ydata',dataToPlot(ch,:)+ch);
            end
            % app.UIAxes_EMG.XLim = [max([t(1),0]) max([t(end),app.plotTime])];
            tmp = ceil(t(end)/app.plotTime);
            app.UIAxes_EMG.XLim = [(tmp-1)*app.plotTime,tmp*app.plotTime];
            drawnow;
        end
        function plotData_all(app,data)
            cla(app.UIAxes_EMG);
            chInt = 8;
            app.plotEMG = multiChanPlotGeneration(size(data,1)/chInt,app.UIAxes_EMG);
            t = [1:length(data)]/app.samplingRate;
            for ch = chInt:chInt:size(data,1)
                set(app.plotEMG{ch/chInt},'xdata',t,'ydata',data(ch,:)+ch/chInt);
            end
            app.UIAxes_EMG.XLim = [0,length(data)/app.samplingRate];
            app.UIAxes_EMG.YLim = [0,size(data,1)/chInt+1];
        end
        function plotAuxData(app,type,data,t)
            switch type
                case 'offline'
                    if strcmp(app.forceDevice,'Handwriting')
                        set(app.plotForce,'string',data);
                    elseif strcmp(app.forceDevice,'Gestures')
                    else
                        if max(abs(data))<2
                            data = data*100;
                            tmpF = app.forcePara.fsamp;%app.samplingRate/app.recordWin;
                        else
                            tmpF = app.forcePara.fsamp;
                        end
                        t = [1:length(data)]/tmpF;
                        set(app.plotForce,'xdata',t,'ydata',data,'linestyle','-','linewidth',2,'color',[0,0,0]);
                        app.UIAxes_angle.XLim = [0,length(data)/tmpF];
                    end
                case 'online'
                    if strcmp(app.forceDevice,'Handwriting')
                        if isnan(data)
                            set(app.plotForce,'string',[]);
                        else
                            set(app.plotForce,'string',data);
                        end
                    elseif strcmp(app.forceDevice,'Gestures')
                        if t<=90
                            set(app.plotForce.cursor,'xdata',[t,t]*20,'ydata',[500,1000]);
                            set(app.plotForce.shadow,'xdata',[0,0,t*20,t*20],'ydata',[0,500,500,0]+500);
                            set(app.plotForce.shadow2,'xdata',[0,0,0,0],'ydata',[0,0,0,0]);
                            set(app.plotForce.cursor2,'xdata',[-50,0,-25,0,-25]+t*20,'ydata',[250,250,275,250,225]+500);
                        else
                            set(app.plotForce.cursor,'xdata',[t-90,t-90]*20,'ydata',[0,500]);
                            set(app.plotForce.shadow,'xdata',[0,0,1800,1800],'ydata',[0,500,500,0]+500);
                            set(app.plotForce.shadow2,'xdata',[0,0,(t-90)*20,(t-90)*20],'ydata',[0,500,500,0]);
                            set(app.plotForce.cursor2,'xdata',[-50,0,-25,0,-25]+(t-90)*20,'ydata',[250,250,275,250,225]);
                        end
                    else
                        set(app.plotForce,'xdata',t,'ydata',data*100,Marker=".",MarkerSize=40);
                        tmp = ceil(t(end)/app.plotTime);
                        app.UIAxes_angle.XLim = [(tmp-1)*app.plotTime,tmp*app.plotTime];
                    end
                    
                    % app.UIAxes_angle.YLim = [0,100];
                case 'MVC'
                    set(app.plotForce,'xdata',t,'ydata',data,Marker=".",MarkerSize=40);
                    tmp = ceil(t(end)/app.plotTime);
                    app.UIAxes_angle.XLim = [(tmp-1)*app.plotTime,tmp*app.plotTime];
                case 'preview'
                    if strcmp(app.forceDevice,'Handwriting')
                        if isnan(data)
                            set(app.plotForce,'string',[]);
                        else
                            set(app.plotForce,'string',data);
                        end
                    elseif strcmp(app.forceDevice,'Gestures')
                        if t<=90
                            set(app.plotForce.cursor,'xdata',[t,t]*20,'ydata',[500,1000]);
                            set(app.plotForce.shadow,'xdata',[0,0,t*20,t*20],'ydata',[0,500,500,0]+500);
                            set(app.plotForce.cursor2,'xdata',[-50,0,-25,0,-25]+t*20,'ydata',[250,250,275,250,225]+500);
                        else
                            set(app.plotForce.cursor,'xdata',[t-90,t-90]*20,'ydata',[0,500]);
                            set(app.plotForce.shadow,'xdata',[0,0,1800,1800],'ydata',[0,500,500,0]+500);
                            set(app.plotForce.shadow2,'xdata',[0,0,(t-90)*20,(t-90)*20],'ydata',[0,500,500,0]);
                            set(app.plotForce.cursor2,'xdata',[-50,0,-25,0,-25]+(t-90)*20,'ydata',[250,250,275,250,225]);
                        end
                    else
                        set(app.plotForce,'xdata',t,'ydata',data*100,Marker=".",MarkerSize=40);
                        tmp = ceil(t(end)/app.plotTime);
                        app.UIAxes_angle.XLim = [(tmp-1)*app.plotTime,tmp*app.plotTime];
                        app.UIAxes_angle.YLim = [0,100];
                    end
            end

        end
        function plotInterval(app)
            interval = app.IntervalEditField.Value;
            if interval
                for i = 1:20
                    line(app.UIAxes_EMG,[interval,interval]*i,[0,500],'LineWidth',2,'Color','k');
                end
                app.UIAxes_EMG.XLim = [0,app.plotTime];
            end
        end
        function saveData(app)
            fileName = [app.PrefixEditField.Value '_Session' num2str(app.SessionSpinner.Value) '_Task' num2str(app.TaskSpinner.Value) '_Trial' num2str(app.TrialSpinner.Value) '.mat'];
            if isempty(app.PrefixEditField.Value)
                fileName = fileName(2:end);
            end
            if app.DateCheckBox.Value
                fileName = [fileName(1:end-4) '_' char(datetime("today")) '.mat'];
            end
            data_sEMG = app.data_sEMG;
            save(fileName,'data_sEMG');
%             if app.ForceCheckBox.Value
%                 if strcmp(app.forceDevice,'Handwriting')
%                     data_label = app.data_force;
%                     save(fileName,'data_label','-append');
%                 else
%                     data_force = app.data_force;
%                     data_mvc = app.mvcData;
%                     save(fileName,'data_force','data_mvc','-append');
%                 end
%             end
            app.TrialSpinner.Value = app.TrialSpinner.Value+1;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConfigureButton
        function ConfigureButtonPushed(app, event)
            app.ConfigureButton.BackgroundColor = [1,1,1];
            app.deviceName = app.DeviceDropDown.Value;
            
            switch app.deviceName
                case 'offline'
                    app.recordChannelNum = length(app.recordChannel);
                    app.adn = 16;
                    app.gain = 150;
                    app.samplingRate = 2048;
            end


            [app.bandpassFilterPara(1,:),app.bandpassFilterPara(2,:)] = butter(4,[20/app.samplingRate*2 500/app.samplingRate*2]);
            fo = 50; % power frequency
            q = 10;
            bw = (fo/(app.samplingRate/2))/q;
            [app.combFilterPara(1,:),app.combFilterPara(2,:)] = iircomb(round(app.samplingRate/fo),bw,'notch');
            app.plotTime = 30;
            fprintf('Configuration completed!\n');
            app.ConfigureButton.BackgroundColor = [0,1,0];
        end

        % Callback function
        function PreviewButtonPushed(app, event)
         
        end

        % Callback function
        function ProbeEditFieldValueChanged(app, event)
     
        end

        % Callback function
        function StartButtonPushed(app, event)
  
        end

        % Callback function
        function StopButtonPushed(app, event)

        end

        % Callback function
        function TaskSpinnerValueChanged(app, event)

        end

        % Callback function
        function SessionSpinnerValueChanged(app, event)

        end

        % Callback function
        function AutostopCheckBoxValueChanged(app, event)

        end

        % Callback function
        function FFTButtonPushed(app, event)

        end

        % Callback function
        function PrefixEditFieldValueChanged(app, event)

        end

        % Callback function
        function ForceCheckBoxValueChanged(app, event)

        end

        % Callback function
        function MVCButtonPushed(app, event)

        end

        % Callback function
        function CurveDropDownValueChanged(app, event)

        end

        % Callback function
        function LevelEditFieldValueChanged(app, event)

        end

        % Callback function
        function ScalesEditFieldValueChanged(app, event)

        end

        % Button pushed function: TemplateButton
        function TemplateButtonPushed(app, event)

            fsamp = 2048;
            app.estimationParameters = cell(1,3);
            elenum = 3;
            exFactor = 10;
            % load Mu-tracking templates
            result = load(['./utils/','resultMuCKCTrackingTemplate.mat']);
            decomps = result.result{1,2}{1,1};
            
            for ele = 1:elenum
                ele_index = find(decomps.ele_tag==ele);
                W = decomps.candW(:,ele_index);
                Centroids = decomps.Centroids(ele_index,:);
                app.estimationParameters{ele}.W = W;
                app.estimationParameters{ele}.Centroids = Centroids;
                app.estimationParameters{ele}.exFactor = exFactor;
                app.estimationParameters{ele}.munum = size(W,2);
            end

            app.ele_index_all = arrayfun(@(e) find(decomps.ele_tag == e), 1:elenum, 'UniformOutput', false);
            app.munum = size(decomps.ele_tag,1);
            app.proInd = [6,28 29 64 64+39 64+41 128+16]; 
            app.TemplateButton.BackgroundColor = [0,1,0];

            clear result decomps  

        end

        % Button pushed function: OfflineButton
        function OfflineButtonPushed(app, event)

            app.samplingRate = 2048;
            elenum = 3;
            app.plotAngle = plot(app.UIAxes_angle,0,0,'LineWidth',1,'Color','r');
            app.UIAxes_angle.XLim = [0,app.plotTime];
            app.UIAxes_angle.YLim = [-1,1];
            app.plotGrasp = plot(app.UIAxes_grasp,0,0,'LineWidth',1,'Color','r');
            app.UIAxes_grasp.XLim = [0,app.plotTime];
            app.UIAxes_grasp.YLim = [0,1];
            sub = 15;
            gest = 1;
            dof = 2;
            tr = 2; % 
            fsamp = 2048;
            fs = fsamp;
            % twitch force
            P = 1.2;
            Tr = 80;
            Tl = 10*Tr;
            ttt = 0:1000/fs:Tl;
            twitch = P/Tr.*ttt.*exp(1-ttt./Tr);
            batchsize = 14;
            % load pytorch configurations
            MLP = py.importlib.import_module('module');%加载 angle-LSTM 空模型
            py.importlib.reload(MLP);%加载
            mode = py.importlib.import_module('model_pre');%加载模型参数
            py.importlib.reload(mode);%加载
            models = mode.model_load(pyargs('params_path_a', './utils/lstm_angle.pth','params_path_f', './utils/lstm_force.pth'));
            model_a = models{1};
            model_f = models{2};
            
            predi = py.importlib.import_module('pred');%读取
            py.importlib.reload(predi);%加载
            scaler = py.numpy.load('./utils/scaler.npy');
            scaler = double(scaler);
            pred_array = [];
            pred_array_f = [];
            
            app.OfflineButton.BackgroundColor = [0,1,0];
            app.recordFlag = 1;

            app.recordWin = floor(0.2*app.samplingRate); 
            app.stepLen = round(0.02*app.samplingRate); 
            nCycle_all = inf;
            app.data_sEMG = zeros(app.recordChannelNum,0);
            load(['./offline/',num2str(sub),num2str(gest),num2str(dof),num2str(tr),'.mat']);

     
            batch_size_counter = 0;
            input = zeros(batchsize,6,app.munum);

            nCycle = 0;
            tic;
            while app.recordFlag && nCycle<1400
                nCycle = nCycle+1;
                app.data_sEMG(:,(nCycle-1)*app.stepLen+1:nCycle*app.stepLen) = EMGdata(:,(nCycle-1)*app.stepLen+1:nCycle*app.stepLen);
                if nCycle<10
                    continue
                end
                newEMGdata = app.data_sEMG(:,end-app.recordWin+1:end);
        
                for ele = 1:elenum
                    ele_index = app.ele_index_all{ele};
                    winData = newEMGdata(1+(ele-1)*64:ele*64, :);
                    winData = filter(app.bandpassFilterPara(1,:),app.bandpassFilterPara(2,:), winData, [], 2);
                    winData = filter(app.combFilterPara(1,:),app.combFilterPara(2,:), winData, [], 2);
                    Pulses = winDecomp0406(winData, app.estimationParameters{ele});
                    % twitch-force model
                    for m_index = 1:app.estimationParameters{ele}.munum
                        tmpSpikes = zeros(1, app.recordWin);
                        if ~isempty(Pulses{m_index})
                            tmpSpikes(Pulses{m_index}) = 1;
                        end
                        force_ch = generateForce_ch_0406(tmpSpikes, twitch, Tr);
                        tmpTwitch(ele_index(m_index), nCycle) = mean(force_ch);
                    end
                end

                if nCycle<15
                    continue
                end
                tmp_sample = tmpTwitch(:,end-5:end)';
                batch_size_counter = batch_size_counter+1;
                tmptmp_sample = (tmp_sample-scaler(2))./(scaler(1)-scaler(2));
                input(batch_size_counter,:,:) = tmptmp_sample;

                if batch_size_counter<batchsize
                    continue
                end
                data1 = py.numpy.array(input);
                preds = predi.prediction(pyargs('model_a',model_a,'model_f', model_f,'data', data1));%预测 
                a_pred = double(preds{1});
                f_pred = double(preds{2});
                pred_array = [pred_array; a_pred];
                pred_array_f = [pred_array_f; f_pred];
                start_time = 0;
                if nCycle==28
                    start_time = toc;
                end
                t_angle = start_time+[1:length(pred_array)]/50;
                set(app.plotAngle,'xdata',t_angle,'ydata',pred_array'); % 只更新数据，不更新样式
                set(app.plotGrasp,'xdata',t_angle,'ydata',pred_array_f');
                drawnow limitrate;
                batch_size_counter = 0;
                input = zeros(batchsize,6,app.munum);
     
            end
            app.OfflineButton.BackgroundColor = [1,1,1];
            set(app.plotAngle,'xdata',0,'ydata',0);
            set(app.plotGrasp,'xdata',0,'ydata',0);

        end

        % Callback function
        function robotValueChanged(app, event)

        end

        % Callback function
        function referenceValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_angle = uiaxes(app.UIFigure);
            title(app.UIAxes_angle, 'Predicted Angle')
            xlabel(app.UIAxes_angle, 'Time (s)')
            ylabel(app.UIAxes_angle, 'Normalized Angle')
            zlabel(app.UIAxes_angle, 'Z')
            app.UIAxes_grasp = uiaxes(app.UIFigure);
            title(app.UIAxes_grasp, 'Predicted Force')
            xlabel(app.UIAxes_grasp, 'Time (s)')
            ylabel(app.UIAxes_grasp, 'Normalized Force')
            zlabel(app.UIAxes_grasp, 'Z')
            app.RecordPanel = uipanel(app.UIFigure);
            app.RecordPanel.Title = 'Record';
            app.TemplateButton = uibutton(app.RecordPanel, 'push');
            app.TemplateButton.ButtonPushedFcn = createCallbackFcn(app, @TemplateButtonPushed, true);
            app.TemplateButton.Text = 'Template';
            app.OfflineButton = uibutton(app.RecordPanel, 'push');
            app.OfflineButton.ButtonPushedFcn = createCallbackFcn(app, @OfflineButtonPushed, true);
            app.OfflineButton.Text = 'Offline';
            app.ConfigurePanel = uipanel(app.UIFigure);
            app.ConfigurePanel.Title = 'Configure';
            app.ConfigureButton = uibutton(app.ConfigurePanel, 'push');
            app.ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureButtonPushed, true);
            app.ConfigureButton.Text = 'Configure';
            app.DeviceDropDownLabel = uilabel(app.ConfigurePanel);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.ConfigurePanel);
            app.DeviceDropDown.Items = {'offline'};
            app.DeviceDropDown.Value = 'offline';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PonlineDemo_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end